classdef realtime_EEG < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        GridLayout             matlab.ui.container.GridLayout
        LabelCmd               matlab.ui.control.Label
        ConnectButton          matlab.ui.control.Button
        EEGPortEditField       matlab.ui.control.EditField
        portEditFieldLabel     matlab.ui.control.Label
        EEGIPEditField         matlab.ui.control.EditField
        EEGIPEditFieldLabel    matlab.ui.control.Label
        LampConnect            matlab.ui.control.Lamp
        LampDatastream         matlab.ui.control.Lamp
        DataStreamSwitch       matlab.ui.control.ToggleSwitch
        DataStreamSwitchLabel  matlab.ui.control.Label
    end

    
    properties (Access = public)
        rtEEG_obj % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.rtEEG_obj.ip=app.EEGIPEditField.Value;
            app.rtEEG_obj.port=app.EEGPortEditField.Value;
            app.rtEEG_obj.updObj=[];

            app.LampConnect.Color='r';
            app.LampDatastream.Color='r';
        end

        % Button pushed function: ConnectButton
        function ConnectButtonPushed(app, event)
            %try to connect to the EEG
            app.rtEEG_obj.ip=app.EEGIPEditField.Value;
            app.rtEEG_obj.port=app.EEGPortEditField.Value;

            try
                app.rtEEG_obj.updObj=udpport('LocalHost',app.rtEEG_obj.ip,'LocalPort',app.rtEEG_obj.port);
                app.LampConnect.Color='g';
            catch
                
            end;
        end

        % Value changed function: DataStreamSwitch
        function DataStreamSwitchValueChanged(app, event)
            value = app.DataStreamSwitch.Value;


            while true


                try
                    rteeg_decode;


                if(strcmp(get(app.DataStreamSwitch,'Value'),'Off'))
                    break;
                end;
                pause(0.01)
                app.LampDatastream.Color='g';

                catch
                    break;
                end;

            end;

            app.LampDatastream.Color='r';

% 
%             if(strcmp(value,'On'))
%                 app.LampDatastream.Color='g';
% 
%                 while true
% 
%                     fprintf('\n');
%                     stop_state = strcmp(app.DataStreamSwitch.Value,'Off');
%                     if stop_state
%                         break;
%                     end
% 
%                 end;
%             else
%                 app.LampDatastream.Color='r';
%             end;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.DataStreamSwitchLabel = uilabel(app.GridLayout);
            app.DataStreamSwitchLabel.Text = 'Data Stream';
            app.DataStreamSwitch = uiswitch(app.GridLayout, 'toggle');
            app.DataStreamSwitch.ValueChangedFcn = createCallbackFcn(app, @DataStreamSwitchValueChanged, true);
            app.LampDatastream = uilamp(app.GridLayout);
            app.LampConnect = uilamp(app.GridLayout);
            app.EEGIPEditFieldLabel = uilabel(app.GridLayout);
            app.EEGIPEditFieldLabel.Text = 'EEG  IP';
            app.EEGIPEditField = uieditfield(app.GridLayout, 'text');
            app.EEGIPEditField.Value = '192.168.200.240';
            app.portEditFieldLabel = uilabel(app.GridLayout);
            app.portEditFieldLabel.Text = 'port';
            app.EEGPortEditField = uieditfield(app.GridLayout, 'text');
            app.EEGPortEditField.Value = '50000';
            app.ConnectButton = uibutton(app.GridLayout, 'push');
            app.ConnectButton.ButtonPushedFcn = createCallbackFcn(app, @ConnectButtonPushed, true);
            app.ConnectButton.Text = 'Connect';
            app.LabelCmd = uilabel(app.GridLayout);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = realtime_EEG

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end